classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HistogramTab               matlab.ui.container.Tab
        HistogramImage             matlab.ui.control.Image
        OpenedFileLabel            matlab.ui.control.Label
        OpenButton                 matlab.ui.control.Button
        HistogramUIAxes            matlab.ui.control.UIAxes
        ImageBrighteningTab        matlab.ui.container.Tab
        AfterLabel                 matlab.ui.control.Label
        BrighteningButton          matlab.ui.control.Button
        bEditField                 matlab.ui.control.EditField
        bEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.EditField
        aEditFieldLabel            matlab.ui.control.Label
        BrighAfterImage            matlab.ui.control.Image
        BrightBeforeImage          matlab.ui.control.Image
        BeforeLabel                matlab.ui.control.Label
        BrightOpenedLabel          matlab.ui.control.Label
        BrightOpenButton           matlab.ui.control.Button
        BrightAfterAxes            matlab.ui.control.UIAxes
        BrightBeforeAxes           matlab.ui.control.UIAxes
        LogTransformationTab       matlab.ui.container.Tab
        PowerTransformationTab     matlab.ui.container.Tab
        ContrastStretchingTab      matlab.ui.container.Tab
        HistogramEquilizationTab   matlab.ui.container.Tab
        HistogramSpecificationTab  matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.OpenedFileLabel.Text = filename;
            app.HistogramImage.ImageSource = filename;
            hist = raw_histogram(filename);
            bar(app.HistogramUIAxes, hist(1,:), hist(2,:));
        end

        % Button pushed function: BrightOpenButton
        function BrightOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.BrightOpenedLabel.Text = filename;
            app.BrightBeforeImage.ImageSource = filename;
            before_hist = raw_histogram(filename);
            bar(app.BrightBeforeAxes, before_hist(1,:), before_hist(2,:));

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.HistogramUIAxes = uiaxes(app.HistogramTab);
            title(app.HistogramUIAxes, 'Histogram')
            xlabel(app.HistogramUIAxes, 'Gray Level')
            ylabel(app.HistogramUIAxes, 'No. of Pixels')
            zlabel(app.HistogramUIAxes, 'Z')
            app.HistogramUIAxes.XTick = [0 50 100 150 200 250];
            app.HistogramUIAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.OpenButton = uibutton(app.HistogramTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.OpenedFileLabel = uilabel(app.HistogramTab);
            app.OpenedFileLabel.Text = '';
            app.HistogramImage = uiimage(app.HistogramTab);
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.BrightBeforeAxes = uiaxes(app.ImageBrighteningTab);
            title(app.BrightBeforeAxes, 'Histogram')
            xlabel(app.BrightBeforeAxes, 'Gray Level')
            ylabel(app.BrightBeforeAxes, 'No. of Pixels')
            zlabel(app.BrightBeforeAxes, 'Z')
            app.BrightBeforeAxes.XTick = [0 50 100 150 200 250];
            app.BrightBeforeAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.BrightAfterAxes = uiaxes(app.ImageBrighteningTab);
            title(app.BrightAfterAxes, 'Histogram')
            xlabel(app.BrightAfterAxes, 'Gray Level')
            ylabel(app.BrightAfterAxes, 'No. of Pixels')
            zlabel(app.BrightAfterAxes, 'Z')
            app.BrightAfterAxes.XTick = [0 50 100 150 200 250];
            app.BrightAfterAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.BrightOpenButton = uibutton(app.ImageBrighteningTab, 'push');
            app.BrightOpenButton.ButtonPushedFcn = createCallbackFcn(app, @BrightOpenButtonPushed, true);
            app.BrightOpenButton.Text = 'Open';
            app.BrightOpenedLabel = uilabel(app.ImageBrighteningTab);
            app.BrightOpenedLabel.Text = '';
            app.BeforeLabel = uilabel(app.ImageBrighteningTab);
            app.BeforeLabel.Text = 'Before';
            app.BrightBeforeImage = uiimage(app.ImageBrighteningTab);
            app.BrighAfterImage = uiimage(app.ImageBrighteningTab);
            app.aEditFieldLabel = uilabel(app.ImageBrighteningTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.ImageBrighteningTab, 'text');
            app.bEditFieldLabel = uilabel(app.ImageBrighteningTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ImageBrighteningTab, 'text');
            app.BrighteningButton = uibutton(app.ImageBrighteningTab, 'push');
            app.BrighteningButton.Text = 'Brightening';
            app.AfterLabel = uilabel(app.ImageBrighteningTab);
            app.AfterLabel.Text = 'After';
            app.LogTransformationTab = uitab(app.TabGroup);
            app.LogTransformationTab.Title = 'Log Transformation';
            app.PowerTransformationTab = uitab(app.TabGroup);
            app.PowerTransformationTab.Title = 'Power Transformation';
            app.ContrastStretchingTab = uitab(app.TabGroup);
            app.ContrastStretchingTab.Title = 'Contrast Stretching';
            app.HistogramEquilizationTab = uitab(app.TabGroup);
            app.HistogramEquilizationTab.Title = 'Histogram Equilization';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end